classdef DiseaseUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BATOPTIMIZEDSVMDISEASEDIAGNOSISASSISTANTLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        AvailableDiseasesTab            matlab.ui.container.Tab
        SelectDiseaseListBoxLabel       matlab.ui.control.Label
        SelectDiseaseListBox            matlab.ui.control.ListBox
        DescriptionTextAreaLabel        matlab.ui.control.Label
        DescriptionTextArea             matlab.ui.control.TextArea
        GOTOTRAININGButton              matlab.ui.control.Button
        AvalaibleDiseasesLabel          matlab.ui.control.Label
        DatasetClassesListBoxLabel      matlab.ui.control.Label
        DatasetClassesListBox           matlab.ui.control.ListBox
        ChangeDirectoryButton           matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        DiseaseEditField_2Label         matlab.ui.control.Label
        DiseaseEditField_2              matlab.ui.control.EditField
        TrainModelTab                   matlab.ui.container.Tab
        SelectDiseaseDropDownLabel      matlab.ui.control.Label
        SelectDiseaseDropDown           matlab.ui.control.DropDown
        TrainButton                     matlab.ui.control.Button
        OptimizationDropDownLabel       matlab.ui.control.Label
        OptimizationDropDown            matlab.ui.control.DropDown
        ClassesListBoxLabel             matlab.ui.control.Label
        ClassesListBox                  matlab.ui.control.ListBox
        ModelAccuracyEditFieldLabel     matlab.ui.control.Label
        ModelAccuracyEditField          matlab.ui.control.NumericEditField
        DiseaseEditFieldLabel           matlab.ui.control.Label
        DiseaseEditField                matlab.ui.control.EditField
        ConfusionChartPanel             matlab.ui.container.Panel
        TrainModelLabel                 matlab.ui.control.Label
        SaveModelButton                 matlab.ui.control.Button
        ChooseRangeforKernelandBoxConstraintLabel  matlab.ui.control.Label
        TimetakenTextAreaLabel          matlab.ui.control.Label
        TimetakenTextArea               matlab.ui.control.TextArea
        BestKernelScaleEditFieldLabel   matlab.ui.control.Label
        BestKernelScaleEditField        matlab.ui.control.NumericEditField
        BestBoxConstraintEditFieldLabel  matlab.ui.control.Label
        BestBoxConstraintEditField      matlab.ui.control.NumericEditField
        RangeSliderLabel                matlab.ui.control.Label
        RangeSlider                     matlab.ui.control.Slider
        Label                           matlab.ui.control.Label
        DiagnosePatientTab              matlab.ui.container.Tab
        SelectModelDropDownLabel        matlab.ui.control.Label
        SelectModelDropDown             matlab.ui.control.DropDown
        DIAGNOSEPATIENTLabel            matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        AcuteInflammationTab            matlab.ui.container.Tab
        TemperatureEditFieldLabel       matlab.ui.control.Label
        TemperatureEditField            matlab.ui.control.EditField
        OccurrenceofnauseaEditFieldLabel  matlab.ui.control.Label
        OccurrenceofnauseaEditField     matlab.ui.control.EditField
        LumbarpainEditFieldLabel        matlab.ui.control.Label
        LumbarpainEditField             matlab.ui.control.EditField
        UrinepushingEditFieldLabel      matlab.ui.control.Label
        UrinepushingEditField           matlab.ui.control.EditField
        MicturitionEditFieldLabel       matlab.ui.control.Label
        MicturitionEditField            matlab.ui.control.EditField
        BurningofurethraEditFieldLabel  matlab.ui.control.Label
        BurningofurethraEditField       matlab.ui.control.EditField
        indegreesLabel                  matlab.ui.control.Label
        yesnoLabel                      matlab.ui.control.Label
        yesnoLabel_2                    matlab.ui.control.Label
        yesnoLabel_5                    matlab.ui.control.Label
        yesnoLabel_3                    matlab.ui.control.Label
        yesnoLabel_4                    matlab.ui.control.Label
        DiagnoseAcuteButton             matlab.ui.control.Button
        PredictionEditField_2           matlab.ui.control.EditField
        InsertsymptomsaccordingtolabelsontheleftLabel  matlab.ui.control.Label
        ThissoftwareisonlyauthorizedforusebymedicalexpertsLabel  matlab.ui.control.Label
        BreastCancerCoimbraTab          matlab.ui.container.Tab
        AgeEditFieldLabel               matlab.ui.control.Label
        AgeEditField                    matlab.ui.control.NumericEditField
        BMIEditFieldLabel               matlab.ui.control.Label
        BMIEditField                    matlab.ui.control.NumericEditField
        GlucoseEditFieldLabel           matlab.ui.control.Label
        GlucoseEditField                matlab.ui.control.NumericEditField
        InsulinEditFieldLabel           matlab.ui.control.Label
        InsulinEditField                matlab.ui.control.NumericEditField
        ResistinEditFieldLabel          matlab.ui.control.Label
        ResistinEditField               matlab.ui.control.NumericEditField
        HOMAEditFieldLabel              matlab.ui.control.Label
        HOMAEditField                   matlab.ui.control.NumericEditField
        LeptinEditFieldLabel            matlab.ui.control.Label
        LeptinEditField                 matlab.ui.control.NumericEditField
        AdiponectinEditFieldLabel       matlab.ui.control.Label
        AdiponectinEditField            matlab.ui.control.NumericEditField
        MCP1EditFieldLabel              matlab.ui.control.Label
        MCP1EditField                   matlab.ui.control.NumericEditField
        DiagnoseCoimbraButton           matlab.ui.control.Button
        PredictionEditField             matlab.ui.control.EditField
        ThissoftwareisonlyauthorizedforusebymedicalexpertsLabel_5  matlab.ui.control.Label
        EnternumericalequivalentsofsymptomsLabel_4  matlab.ui.control.Label
        BreastCancerWisconsinTab        matlab.ui.container.Tab
        DiagnoseBreastCancerButton      matlab.ui.control.Button
        PredictionEditField_3           matlab.ui.control.EditField
        ThissoftwareisonlyauthorizedforusebymedicalexpertsLabel_2  matlab.ui.control.Label
        ClumpThicknessEditFieldLabel    matlab.ui.control.Label
        ClumpThicknessEditField         matlab.ui.control.NumericEditField
        UniformityofCellSizeEditField_2Label  matlab.ui.control.Label
        UniformityofCellSizeEditField_2  matlab.ui.control.NumericEditField
        UniformityofCellShapeEditField_2Label  matlab.ui.control.Label
        UniformityofCellShapeEditField_2  matlab.ui.control.NumericEditField
        MarginalAdhesionEditField_2Label  matlab.ui.control.Label
        MarginalAdhesionEditField_2     matlab.ui.control.NumericEditField
        SingleEpithelialCellEditField_2Label  matlab.ui.control.Label
        SingleEpithelialCellEditField_2  matlab.ui.control.NumericEditField
        BareNucleiBlandEditFieldLabel   matlab.ui.control.Label
        BareNucleiBlandEditField        matlab.ui.control.NumericEditField
        Var8EditField_2Label            matlab.ui.control.Label
        Var8EditField_2                 matlab.ui.control.NumericEditField
        NormalNucleiEditField_2Label    matlab.ui.control.Label
        NormalNucleiEditField_2         matlab.ui.control.NumericEditField
        MitosisEditFieldLabel           matlab.ui.control.Label
        MitosisEditField                matlab.ui.control.NumericEditField
        EnternumericalequivalentsofsymptomsLabel_3  matlab.ui.control.Label
        CervicalCancerTab               matlab.ui.container.Tab
        DiagnoseCervicalCancerButton    matlab.ui.control.Button
        PredictionEditField_6           matlab.ui.control.EditField
        ThissoftwareisonlyauthorizedforusebymedicalexpertsLabel_6  matlab.ui.control.Label
        EnternumericalequivalentsofsymptomsLabel_5  matlab.ui.control.Label
        behavior_eatingEditField_2Label  matlab.ui.control.Label
        behavior_eatingEditField_2      matlab.ui.control.NumericEditField
        behavior_personalHygineEditField_2Label  matlab.ui.control.Label
        behavior_personalHygineEditField_2  matlab.ui.control.NumericEditField
        intention_aggregationEditField_2Label  matlab.ui.control.Label
        intention_aggregationEditField_2  matlab.ui.control.NumericEditField
        intention_commitmentEditField_2Label  matlab.ui.control.Label
        intention_commitmentEditField_2  matlab.ui.control.NumericEditField
        attitude_consistencyEditField_2Label  matlab.ui.control.Label
        attitude_consistencyEditField_2  matlab.ui.control.NumericEditField
        attitude_spontaneityEditField_2Label  matlab.ui.control.Label
        attitude_spontaneityEditField_2  matlab.ui.control.NumericEditField
        norm_significantPersonEditFieldLabel  matlab.ui.control.Label
        norm_significantPersonEditField  matlab.ui.control.NumericEditField
        perception_vulnerabilityEditField_2Label  matlab.ui.control.Label
        perception_vulnerabilityEditField_2  matlab.ui.control.NumericEditField
        perception_severityEditField_2Label  matlab.ui.control.Label
        perception_severityEditField_2  matlab.ui.control.NumericEditField
        motivation_strengthEditField_2Label  matlab.ui.control.Label
        motivation_strengthEditField_2  matlab.ui.control.NumericEditField
        motivation_willingnessEditField_2Label  matlab.ui.control.Label
        motivation_willingnessEditField_2  matlab.ui.control.NumericEditField
        socialSupport_emotionalityEditField_2Label  matlab.ui.control.Label
        socialSupport_emotionalityEditField_2  matlab.ui.control.NumericEditField
        socialSupport_appreciationEditField_2Label  matlab.ui.control.Label
        socialSupport_appreciationEditField_2  matlab.ui.control.NumericEditField
        socialSupport_instrumentalEditFieldLabel  matlab.ui.control.Label
        socialSupport_instrumentalEditField  matlab.ui.control.NumericEditField
        empowerment_knowledgeEditFieldLabel  matlab.ui.control.Label
        empowerment_knowledgeEditField  matlab.ui.control.NumericEditField
        empowerment_abilitiesEditFieldLabel  matlab.ui.control.Label
        empowerment_abilitiesEditField  matlab.ui.control.NumericEditField
        empowerment_desiresEditFieldLabel  matlab.ui.control.Label
        empowerment_desiresEditField    matlab.ui.control.NumericEditField
        BehaviorsexualriskEditFieldLabel  matlab.ui.control.Label
        BehaviorsexualriskEditField     matlab.ui.control.NumericEditField
        norm_fulfillmentEditField_3Label  matlab.ui.control.Label
        norm_fulfillmentEditField_3     matlab.ui.control.NumericEditField
        LymphographyTab                 matlab.ui.container.Tab
        DiagnoseLymphographyButton      matlab.ui.control.Button
        PredictionEditField_7           matlab.ui.control.EditField
        ThissoftwareisonlyauthorizedforusebymedicalexpertsLabel_7  matlab.ui.control.Label
        EnternumericalequivalentsofsymptomsLabel_6  matlab.ui.control.Label
        LymphaticsblockofaffereEditFieldLabel  matlab.ui.control.Label
        LymphaticsblockofaffereEditField  matlab.ui.control.NumericEditField
        LymphaticsblockEditFieldLabel   matlab.ui.control.Label
        LymphaticsblockEditField        matlab.ui.control.NumericEditField
        BloflymphCEditFieldLabel        matlab.ui.control.Label
        BloflymphCEditField             matlab.ui.control.NumericEditField
        BloflymphSEditFieldLabel        matlab.ui.control.Label
        BloflymphSEditField             matlab.ui.control.NumericEditField
        BypassEditFieldLabel            matlab.ui.control.Label
        BypassEditField                 matlab.ui.control.NumericEditField
        ExtravasatesEditFieldLabel      matlab.ui.control.Label
        ExtravasatesEditField           matlab.ui.control.NumericEditField
        RegenerationEditFieldLabel      matlab.ui.control.Label
        RegenerationEditField           matlab.ui.control.NumericEditField
        EarlyuptakeinEditFieldLabel     matlab.ui.control.Label
        EarlyuptakeinEditField          matlab.ui.control.NumericEditField
        LymnodesdiminEditFieldLabel     matlab.ui.control.Label
        LymnodesdiminEditField          matlab.ui.control.NumericEditField
        LymnodesenlarEditFieldLabel     matlab.ui.control.Label
        LymnodesenlarEditField          matlab.ui.control.NumericEditField
        ChangesinlymEditFieldLabel      matlab.ui.control.Label
        ChangesinlymEditField           matlab.ui.control.NumericEditField
        DefectinnodeEditFieldLabel      matlab.ui.control.Label
        DefectinnodeEditField           matlab.ui.control.NumericEditField
        ChangesinnodeEditFieldLabel     matlab.ui.control.Label
        ChangesinnodeEditField          matlab.ui.control.NumericEditField
        SpecialformsEditFieldLabel      matlab.ui.control.Label
        SpecialformsEditField           matlab.ui.control.NumericEditField
        DislocationofEditFieldLabel     matlab.ui.control.Label
        DislocationofEditField          matlab.ui.control.NumericEditField
        ExclusionofnoEditFieldLabel     matlab.ui.control.Label
        ExclusionofnoEditField          matlab.ui.control.NumericEditField
        NoofnodesinEditFieldLabel       matlab.ui.control.Label
        NoofnodesinEditField            matlab.ui.control.NumericEditField
        ChangesinstruEditField_2Label   matlab.ui.control.Label
        ChangesinstruEditField_2        matlab.ui.control.NumericEditField
        CrossValidationEditField_2Label  matlab.ui.control.Label
        CrossValidationEditField_2      matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        SvmModel % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
            %Get Directory of Datasets        
        fileList= dir(fullfile('C:\','Users\','Alvin\','Documents\','SvmGUI\','Datasets\','*.csv'));
        fnames = {fileList.name};
        app.SelectDiseaseListBox.Items=fnames;
        app.SelectDiseaseDropDown.Items=fnames;
        
        % Get List of Optimization Options Available
        fileList= dir(fullfile('C:\','Users\','Alvin\','Documents\','SvmGUI\','Optimization\','*.m'));
        fnames = {fileList.name};
        app.OptimizationDropDown.Items=fnames;
        
         %Get Directory of Models
        trainedModelsList=dir(fullfile('C:\','Users\','Alvin\','Documents\','SvmGUI\','TrainedModels\','*.mat'));
        fmodels = {trainedModelsList.name};
        app.SelectModelDropDown.Items=fmodels;
        nameinmodel = app.SelectModelDropDown.Value;
        ModelLocationDataset='C:\Users\Alvin\Documents\SvmGUI\Datasets\';
        
                
        ModelLocation='C:\Users\Alvin\Documents\SvmGUI\TrainedModels\';
        TrainedModelsLocation = append(ModelLocation,nameinmodel);
        [~,classLoss]= modelInformation(TrainedModelsLocation);
        app.CrossValidationEditField_2.Value=classLoss;
        
        nameinmodel = strrep(app.SelectModelDropDown.Value,'.mat','.csv');


        TrainedModelsDatasetLocation = append(ModelLocationDataset,nameinmodel); 
        [~,datanamesmodel]=showSelectedTable(TrainedModelsDatasetLocation);


        app.DiseaseEditField_2.Value=app.SelectDiseaseListBox.Value;
        app.DiseaseEditField.Value=app.SelectDiseaseDropDown.Value;


        DatasetDirectory='C:\Users\Alvin\Documents\SvmGUI\Datasets\';
        actualFile = app.DiseaseEditField_2.Value;
        DatasetLocation = append(DatasetDirectory,actualFile);


        [datatable,datanames]=showSelectedTable(DatasetLocation);
        
        [class]=showSelectedClass(DatasetLocation);
        [svmSaved]=choosePreProcessData(DatasetLocation);

        
         DataDescription='C:\Users\Alvin\Documents\SvmGUI\Datasets\';
         
         nameintxt = strrep(app.SelectDiseaseListBox.Value,'.csv','.txt');
         DescriptiontLocation = append(DataDescription,nameintxt);


         fileID = fopen(DescriptiontLocation,'r');
         if fileID<1, error([msg ' File: ' DescriptiontLocation]), end
         textdata = fread(fileID, inf, 'uint8=>char')';
         app.DescriptionTextArea.Value=textdata;


        app.UITable.Data=datatable;
        app.UITable.ColumnName=datanames; 

        app.DatasetClassesListBox.Items=class;
        app.ClassesListBox.Items=class;


        end

        % Value changed function: SelectDiseaseListBox
        function SelectDiseaseListBoxValueChanged(app, event)
                    
           app.DiseaseEditField_2.Value=app.SelectDiseaseListBox.Value;
           
           DatasetDirectory='C:\Users\Alvin\Documents\SvmGUI\Datasets\';
           actualFile = app.DiseaseEditField_2.Value;
           DatasetLocation = append(DatasetDirectory,actualFile);
            
           [datatable,datanames]=showSelectedTable(DatasetLocation);
           [class]=showSelectedClass(DatasetLocation);
            
           DataDescription='C:\Users\Alvin\Documents\SvmGUI\Datasets\';
         
           nameintxt = strrep(app.SelectDiseaseListBox.Value,'.csv','.txt');
           DescriptiontLocation = append(DataDescription,nameintxt)


         fileID = fopen(DescriptiontLocation,'r');
         if fileID<1, error([msg ' File: ' DescriptiontLocation]), end
         textdata = fread(fileID, inf, 'uint8=>char')';
         app.DescriptionTextArea.Value=textdata;

           
           app.UITable.Data=datatable;
           app.UITable.ColumnName=datanames; 
           app.DatasetClassesListBox.Items=class;


        end

        % Button pushed function: ChangeDirectoryButton
        function ChangeDirectoryButtonPushed(app, event)
           selPath=uigetdir;
           fileList= dir(fullfile(selPath,'*.csv'));
           fnames = {fileList.name};
           app.SelectDiseaseListBox.Items=fnames;
           

        end

        % Button pushed function: GOTOTRAININGButton
        function GOTOTRAININGButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.TrainModelTab;
        end

        % Value changed function: SelectDiseaseDropDown
        function SelectDiseaseDropDownValueChanged(app, event)
            value = app.SelectDiseaseDropDown.Value;
            fileList= dir(fullfile('C:\','Users\','Alvin\','Documents\','SvmGUI\','Datasets\','*.csv'));
            fnames = {fileList.name};
            app.SelectDiseaseDropDown.Items=fnames;
            
            DatasetDirectory='C:\Users\Alvin\Documents\SvmGUI\Datasets\';
            actualFile = app.SelectDiseaseDropDown.Value;
            DatasetLocation = append(DatasetDirectory,actualFile);
            [class]=showSelectedClass(DatasetLocation);
            [svmSaved]=choosePreProcessData(DatasetLocation);
            app.ClassesListBox.Items=class;
            
            app.DiseaseEditField.Value=app.SelectDiseaseDropDown.Value;
            
            app.ModelAccuracyEditField.Value=0;
            h=heatmap(app.ConfusionChartPanel,0,'Title','Confusion Matrix','XLabel','Actual Diagnosis','YLabel','Predicted Diagnosis');
            
             app.ModelAccuracyEditField.Value=0;
            app.BestKernelScaleEditField.Value=0;
            app.BestBoxConstraintEditField.Value=0;
            app.TimetakenTextArea.Value='0' ;


        end

        % Value changed function: ClassesListBox
        function ClassesListBoxValueChanged(app, event)
            value = app.ClassesListBox.Value;
           
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
             actualFile = app.OptimizationDropDown.Value;
            app.ModelAccuracyEditField.Value=0;
            app.BestKernelScaleEditField.Value=0;
            app.BestBoxConstraintEditField.Value=0;
            app.TimetakenTextArea.Value='0' ;

            [final_model_accuracy,conMat,finalMdl,time_duration,bestKscale,bestBConstraint]=chooseOptimization(actualFile,app.RangeSlider.Value);
        
            
            %app.ModelAccuracyEditField.Value=final_model_accuracy;
            app.BestKernelScaleEditField.Value=bestKscale;
            app.BestBoxConstraintEditField.Value=bestBConstraint;
            app.TimetakenTextArea.Value=time_duration ;         
            h=heatmap(app.ConfusionChartPanel,conMat,'Title','Confusion Matrix','XLabel','Actual Diagnosis','YLabel','Predicted Diagnosis');
            app.SvmModel=finalMdl;

        end

        % Button pushed function: SaveModelButton
        function SaveModelButtonPushed(app, event)
        
        nameinmat = strrep(app.DiseaseEditField.Value,'.csv','.mat');
        savingLocation='C:\Users\Alvin\Documents\SvmGUI\TrainedModels\';
        TrainedModelsLocation = append(savingLocation,nameinmat);
        
        svm_model_save=app.SvmModel;
        
        save(TrainedModelsLocation,'svm_model_save');
        
        trainedModelsList=dir(fullfile('C:\','Users\','Alvin\','Documents\','SvmGUI\','TrainedModels\','*.mat'));
        fmodels = {trainedModelsList.name};
        app.SelectModelDropDown.Items=fmodels;


        app.TabGroup.SelectedTab = app.DiagnosePatientTab;

        end

        % Value changed function: SelectModelDropDown
        function SelectModelDropDownValueChanged(app, event)
            value = app.SelectModelDropDown.Value;
            subPredictTab = strrep(app.SelectModelDropDown.Value,'.mat','')

            
            if strcmp(app.SelectModelDropDown.Value,'acute_inflammation.mat')
                  app.TabGroup2.SelectedTab = app.AcuteInflammationTab;
            elseif strcmp(app.SelectModelDropDown.Value,'coimbra.mat')
                  app.TabGroup2.SelectedTab = app.BreastCancerCoimbraTab;
            elseif strcmp(app.SelectModelDropDown.Value,'cervical_cancer_behavior.mat')
                  app.TabGroup2.SelectedTab = app.CervicalCancerTab;
            elseif strcmp(app.SelectModelDropDown.Value,'lymphography.mat')
                  app.TabGroup2.SelectedTab = app.LymphographyTab;
            elseif strcmp(app.SelectModelDropDown.Value,'breast_cancer_winsconsin_diagnostic.mat')
                  app.TabGroup2.SelectedTab = app.BreastCancerWisconsinTab;
            end


            nameinmodel = app.SelectModelDropDown.Value;
            ModelLocation='C:\Users\Alvin\Documents\SvmGUI\TrainedModels\';
            TrainedModelsLocation = append(ModelLocation,nameinmodel);
            [~,classLoss]= modelInformation(TrainedModelsLocation);
            
             app.CrossValidationEditField_2.Value=classLoss;

            
            ModelLocationDataset='C:\Users\Alvin\Documents\SvmGUI\Datasets\';
        
            csvnameinmodel = strrep(app.SelectModelDropDown.Value,'.mat','.csv');


            TrainedModelsDatasetLocation = append(ModelLocationDataset,csvnameinmodel); 
            [~,datanamesmodel]=showSelectedTable(TrainedModelsDatasetLocation);


        end

        % Value changed function: OptimizationDropDown
        function OptimizationDropDownValueChanged(app, event)
            value = app.OptimizationDropDown.Value;            
            fileList= dir(fullfile('C:\','Users\','Alvin\','Documents\','SvmGUI\','Optimization\','*.m'));
            fnames = {fileList.name};
            app.OptimizationDropDown.Items=fnames;
            
            
        end

        % Button pushed function: DiagnoseAcuteButton
        function DiagnoseAcuteButtonPushed(app, event)
         
         symptoms=[{ app.TemperatureEditField.Value,app.OccurrenceofnauseaEditField.Value,app.LumbarpainEditField.Value,app.UrinepushingEditField.Value,app.MicturitionEditField.Value,app.BurningofurethraEditField.Value}];
         
         nameinmodel = app.SelectModelDropDown.Value;
         ModelLocation='C:\Users\Alvin\Documents\SvmGUI\TrainedModels\';
         TrainedModelsLocation = append(ModelLocation,nameinmodel);
         row_size=size(symptoms,1);
         column_size=size(symptoms,2);
         PatientSymptoms(row_size,column_size)=0;
         PatientSymptoms(:,1)=str2double(symptoms(:,1));

         for count_i=1:column_size
    
         if strcmpi(symptoms(1,count_i),'yes')
         PatientSymptoms(1,count_i)=2;   
        
         elseif strcmpi(symptoms(1,count_i),'no')
         PatientSymptoms(1,count_i)=1;
         end
         end

         %symptoms=[1,2,3,4]
         
         symptoms=[{PatientSymptoms(1,1),PatientSymptoms(1,2),PatientSymptoms(1,3),PatientSymptoms(1,4),PatientSymptoms(1,5),PatientSymptoms(1,6)}]
         
         
         [predictionPatient]=diagnosePatient(TrainedModelsLocation,symptoms);
         if strcmpi(predictionPatient,'1')
         predictionPatient='No Inflammation';   
         elseif strcmpi(predictionPatient,'2')
         predictionPatient='Inflammation';   
         end
         app.PredictionEditField_2.Value=predictionPatient;
         
         
        end

        % Button pushed function: DiagnoseCoimbraButton
        function DiagnoseCoimbraButtonPushed(app, event)
         symptoms=[{app.AgeEditField.Value,app.BMIEditField.Value,app.GlucoseEditField.Value,app.InsulinEditField.Value,app.ResistinEditField.Value,app.HOMAEditField.Value,app.LeptinEditField.Value,app.AdiponectinEditField.Value,app.MCP1EditField.Value}]
         PatientSymptoms(:,:)=str2double(symptoms(:,:));
         
         nameinmodel = app.SelectModelDropDown.Value;
         ModelLocation='C:\Users\Alvin\Documents\SvmGUI\TrainedModels\';
         TrainedModelsLocation = append(ModelLocation,nameinmodel);
         

         %symptoms=[1,2,3,4]
         [predictionPatient]=diagnosePatient(TrainedModelsLocation,symptoms);
         if strcmpi(predictionPatient,'1')
         predictionPatient='Absent';   
         elseif strcmpi(predictionPatient,'2')
         predictionPatient='Present';   
         end
         app.PredictionEditField.Value=predictionPatient;             
        end

        % Callback function
        function DiagnoseCervicalButtonPushed(app, event)
         symptoms=[{ app.AgeEditField.Value,app.BMIEditField.Value,app.GlucoseEditField.Value,app.InsulinEditField.Value,app.ResistinEditField.Value,app.HOMAEditField.Value,app.LeptinEditField.Value,app.AdiponectinEditField.Value,app.MCP1EditField.Value}]
         PatientSymptoms(:,:)=str2double(symptoms(:,:));
         
         nameinmodel = app.SelectModelDropDown.Value;
         ModelLocation='C:\Users\Alvin\Documents\SvmGUI\TrainedModels\';
         TrainedModelsLocation = append(ModelLocation,nameinmodel);
         

         %symptoms=[1,2,3,4]
         [predictionPatient]=diagnosePatient(TrainedModelsLocation,symptoms);
         if strcmpi(predictionPatient,'1')
         predictionPatient='Coimbra ';   
         elseif strcmpi(predictionPatient,'2')
         predictionPatient='Coimbra Absent';   
         end
         app.PredictionEditField.Value=predictionPatient; 
        end

        % Button pushed function: DiagnoseBreastCancerButton
        function DiagnoseBreastCancerButtonPushed(app, event)
         symptoms=[{app.ClumpThicknessEditField.Value app.UniformityofCellSizeEditField_2.Value app.UniformityofCellShapeEditField_2.Value app.MarginalAdhesionEditField_2.Value app.SingleEpithelialCellEditField_2.Value app.BareNucleiBlandEditField.Value app.Var8EditField_2.Value app.NormalNucleiEditField_2.Value app.MitosisEditField.Value}]
         PatientSymptoms(:,:)=str2double(symptoms(:,:));
         
         nameinmodel = app.SelectModelDropDown.Value;
         ModelLocation='C:\Users\Alvin\Documents\SvmGUI\TrainedModels\';
         TrainedModelsLocation = append(ModelLocation,nameinmodel);
         %symptoms=[1,2]
         [predictionPatient]=diagnosePatient(TrainedModelsLocation,symptoms);
         if strcmpi(predictionPatient,'1')
         predictionPatient='Benign';   
         elseif strcmpi(predictionPatient,'2')
         predictionPatient='Malignant';   
         end
         app.PredictionEditField_3.Value=predictionPatient; 
        end

        % Button pushed function: DiagnoseCervicalCancerButton
        function DiagnoseCervicalCancerButtonPushed(app, event)
         symptoms=[{app.BehaviorsexualriskEditField.Value app.behavior_eatingEditField_2.Value app.behavior_personalHygineEditField_2.Value app.intention_aggregationEditField_2.Value app.intention_commitmentEditField_2.Value app.attitude_consistencyEditField_2.Value app.attitude_spontaneityEditField_2.Value app.norm_significantPersonEditField.Value app.norm_fulfillmentEditField_3.Value app.perception_vulnerabilityEditField_2.Value app.perception_severityEditField_2.Value app.motivation_strengthEditField_2.Value app.motivation_willingnessEditField_2.Value app.socialSupport_emotionalityEditField_2.Value app.socialSupport_appreciationEditField_2.Value app.socialSupport_instrumentalEditField.Value app.empowerment_knowledgeEditField.Value app.empowerment_abilitiesEditField.Value app.empowerment_desiresEditField.Value}]
         
         nameinmodel = app.SelectModelDropDown.Value;
         ModelLocation='C:\Users\Alvin\Documents\SvmGUI\TrainedModels\';
         TrainedModelsLocation = append(ModelLocation,nameinmodel);
         %symptoms=[1,2]
         [predictionPatient]=diagnosePatient(TrainedModelsLocation,symptoms);
         if strcmpi(predictionPatient,'1')
         predictionPatient='Cervical Cancer Absent';   
         elseif strcmpi(predictionPatient,'2')
         predictionPatient='Cervical Cancer Present';   
         end
         app.PredictionEditField_6.Value=predictionPatient; 
        end

        % Button pushed function: DiagnoseLymphographyButton
        function DiagnoseLymphographyButtonPushed(app, event)
         
        symptoms=[{app.LymphaticsblockofaffereEditField.Value app.LymphaticsblockEditField.Value app.BloflymphCEditField.Value app.BloflymphSEditField.Value app.BypassEditField.Value app.ExtravasatesEditField.Value app.RegenerationEditField.Value app.EarlyuptakeinEditField.Value app.LymnodesdiminEditField.Value app.LymnodesenlarEditField.Value app.ChangesinlymEditField.Value app.DefectinnodeEditField.Value app.ChangesinnodeEditField.Value app.ChangesinstruEditField_2.Value app.SpecialformsEditField.Value app.DislocationofEditField.Value app.ExclusionofnoEditField.Value app.NoofnodesinEditField.Value}]
         
         nameinmodel = app.SelectModelDropDown.Value;
         ModelLocation='C:\Users\Alvin\Documents\SvmGUI\TrainedModels\';
         TrainedModelsLocation = append(ModelLocation,nameinmodel);
         %symptoms=[1,2]
         [predictionPatient]=diagnosePatient(TrainedModelsLocation,symptoms);
         if strcmpi(predictionPatient,'1')
         predictionPatient='Normal';   
         elseif strcmpi(predictionPatient,'2')
         predictionPatient='Metastases'; 
         elseif strcmpi(predictionPatient,'3')
         predictionPatient='Malign lymph';
         elseif strcmpi(predictionPatient,'4')
         predictionPatient='Fibrosis';
         end
         app.PredictionEditField_7.Value=predictionPatient;            
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            selectedTab = app.TabGroup2.SelectedTab;
            
            if (app.TabGroup2.SelectedTab==app.AcuteInflammationTab)
                app.SelectModelDropDown.Value='acute_inflammation.mat';   
                ModelLocation='C:\Users\Alvin\Documents\SvmGUI\TrainedModels\';
                TrainedModelsLocation = append(ModelLocation,app.SelectModelDropDown.Value);
                [~,classLoss]= modelInformation(TrainedModelsLocation);
                app.CrossValidationEditField_2.Value=classLoss;
             
            elseif (app.TabGroup2.SelectedTab==app.BreastCancerCoimbraTab)
                app.SelectModelDropDown.Value='coimbra.mat';
                ModelLocation='C:\Users\Alvin\Documents\SvmGUI\TrainedModels\';
                TrainedModelsLocation = append(ModelLocation,app.SelectModelDropDown.Value);
                [~,classLoss]= modelInformation(TrainedModelsLocation);
                app.CrossValidationEditField_2.Value=classLoss;
                
            elseif (app.TabGroup2.SelectedTab==app.CervicalCancerTab)
                app.SelectModelDropDown.Value='cervical_cancer_behavior.mat';
                ModelLocation='C:\Users\Alvin\Documents\SvmGUI\TrainedModels\';
                TrainedModelsLocation = append(ModelLocation,app.SelectModelDropDown.Value);
                [~,classLoss]= modelInformation(TrainedModelsLocation);
                app.CrossValidationEditField_2.Value=classLoss;
                
                
            elseif (app.TabGroup2.SelectedTab==app.LymphographyTab)
                app.SelectModelDropDown.Value = 'lymphography.mat';  
                ModelLocation='C:\Users\Alvin\Documents\SvmGUI\TrainedModels\';
                TrainedModelsLocation = append(ModelLocation,app.SelectModelDropDown.Value);
                [~,classLoss]= modelInformation(TrainedModelsLocation);
                app.CrossValidationEditField_2.Value=classLoss;
            elseif (app.TabGroup2.SelectedTab==app.BreastCancerWisconsinTab)
                app.SelectModelDropDown.Value = 'breast_cancer_winsconsin_diagnostic.mat';
                ModelLocation='C:\Users\Alvin\Documents\SvmGUI\TrainedModels\';
                TrainedModelsLocation = append(ModelLocation,app.SelectModelDropDown.Value);
                [~,classLoss]= modelInformation(TrainedModelsLocation);
                app.CrossValidationEditField_2.Value=classLoss;
           
            end

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BATOPTIMIZEDSVMDISEASEDIAGNOSISASSISTANTLabel = uilabel(app.UIFigure);
            app.BATOPTIMIZEDSVMDISEASEDIAGNOSISASSISTANTLabel.Text = 'BAT OPTIMIZED SVM DISEASE DIAGNOSIS ASSISTANT';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AvailableDiseasesTab = uitab(app.TabGroup);
            app.AvailableDiseasesTab.Title = 'Available Diseases';
            app.SelectDiseaseListBoxLabel = uilabel(app.AvailableDiseasesTab);
            app.SelectDiseaseListBoxLabel.Text = 'Select Disease';
            app.SelectDiseaseListBox = uilistbox(app.AvailableDiseasesTab);
            app.SelectDiseaseListBox.ValueChangedFcn = createCallbackFcn(app, @SelectDiseaseListBoxValueChanged, true);
            app.DescriptionTextAreaLabel = uilabel(app.AvailableDiseasesTab);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.AvailableDiseasesTab);
            app.GOTOTRAININGButton = uibutton(app.AvailableDiseasesTab, 'push');
            app.GOTOTRAININGButton.ButtonPushedFcn = createCallbackFcn(app, @GOTOTRAININGButtonPushed, true);
            app.GOTOTRAININGButton.Text = 'GO TO TRAINING';
            app.AvalaibleDiseasesLabel = uilabel(app.AvailableDiseasesTab);
            app.AvalaibleDiseasesLabel.Text = 'Avalaible Diseases';
            app.DatasetClassesListBoxLabel = uilabel(app.AvailableDiseasesTab);
            app.DatasetClassesListBoxLabel.Text = 'Dataset Classes';
            app.DatasetClassesListBox = uilistbox(app.AvailableDiseasesTab);
            app.ChangeDirectoryButton = uibutton(app.AvailableDiseasesTab, 'push');
            app.ChangeDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeDirectoryButtonPushed, true);
            app.ChangeDirectoryButton.Text = 'Change Directory';
            app.UITable = uitable(app.AvailableDiseasesTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.DiseaseEditField_2Label = uilabel(app.AvailableDiseasesTab);
            app.DiseaseEditField_2Label.Text = 'Disease';
            app.DiseaseEditField_2 = uieditfield(app.AvailableDiseasesTab, 'text');
            app.TrainModelTab = uitab(app.TabGroup);
            app.TrainModelTab.Title = 'Train Model';
            app.SelectDiseaseDropDownLabel = uilabel(app.TrainModelTab);
            app.SelectDiseaseDropDownLabel.Text = 'Select Disease';
            app.SelectDiseaseDropDown = uidropdown(app.TrainModelTab);
            app.SelectDiseaseDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectDiseaseDropDownValueChanged, true);
            app.TrainButton = uibutton(app.TrainModelTab, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.OptimizationDropDownLabel = uilabel(app.TrainModelTab);
            app.OptimizationDropDownLabel.Text = 'Optimization ';
            app.OptimizationDropDown = uidropdown(app.TrainModelTab);
            app.OptimizationDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimizationDropDownValueChanged, true);
            app.ClassesListBoxLabel = uilabel(app.TrainModelTab);
            app.ClassesListBoxLabel.Text = 'Classes';
            app.ClassesListBox = uilistbox(app.TrainModelTab);
            app.ClassesListBox.ValueChangedFcn = createCallbackFcn(app, @ClassesListBoxValueChanged, true);
            app.ModelAccuracyEditFieldLabel = uilabel(app.TrainModelTab);
            app.ModelAccuracyEditFieldLabel.Visible = 'off';
            app.ModelAccuracyEditFieldLabel.Text = 'Model Accuracy';
            app.ModelAccuracyEditField = uieditfield(app.TrainModelTab, 'numeric');
            app.ModelAccuracyEditField.Visible = 'off';
            app.DiseaseEditFieldLabel = uilabel(app.TrainModelTab);
            app.DiseaseEditFieldLabel.Text = 'Disease';
            app.DiseaseEditField = uieditfield(app.TrainModelTab, 'text');
            app.ConfusionChartPanel = uipanel(app.TrainModelTab);
            app.ConfusionChartPanel.Title = 'Confusion Chart';
            app.TrainModelLabel = uilabel(app.TrainModelTab);
            app.TrainModelLabel.Text = 'Train Model';
            app.SaveModelButton = uibutton(app.TrainModelTab, 'push');
            app.SaveModelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveModelButtonPushed, true);
            app.SaveModelButton.Text = 'Save Model';
            app.ChooseRangeforKernelandBoxConstraintLabel = uilabel(app.TrainModelTab);
            app.ChooseRangeforKernelandBoxConstraintLabel.Visible = 'off';
            app.ChooseRangeforKernelandBoxConstraintLabel.Text = 'Choose Range for Kernel and Box Constraint';
            app.TimetakenTextAreaLabel = uilabel(app.TrainModelTab);
            app.TimetakenTextAreaLabel.Text = 'Time taken';
            app.TimetakenTextArea = uitextarea(app.TrainModelTab);
            app.BestKernelScaleEditFieldLabel = uilabel(app.TrainModelTab);
            app.BestKernelScaleEditFieldLabel.Text = 'Best Kernel Scale';
            app.BestKernelScaleEditField = uieditfield(app.TrainModelTab, 'numeric');
            app.BestBoxConstraintEditFieldLabel = uilabel(app.TrainModelTab);
            app.BestBoxConstraintEditFieldLabel.Text = 'Best Box Constraint';
            app.BestBoxConstraintEditField = uieditfield(app.TrainModelTab, 'numeric');
            app.RangeSliderLabel = uilabel(app.TrainModelTab);
            app.RangeSliderLabel.Visible = 'off';
            app.RangeSliderLabel.Text = 'Range';
            app.RangeSlider = uislider(app.TrainModelTab);
            app.RangeSlider.Limits = [0 10000];
            app.RangeSlider.Visible = 'off';
            app.RangeSlider.Value = 10000;
            app.Label = uilabel(app.TrainModelTab);
            app.Label.Text = 'Kindly be patient after clicking train. It should ideally take less than 60 seconds';
            app.DiagnosePatientTab = uitab(app.TabGroup);
            app.DiagnosePatientTab.Title = 'Diagnose Patient';
            app.SelectModelDropDownLabel = uilabel(app.DiagnosePatientTab);
            app.SelectModelDropDownLabel.Text = 'Select Model';
            app.SelectModelDropDown = uidropdown(app.DiagnosePatientTab);
            app.SelectModelDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectModelDropDownValueChanged, true);
            app.DIAGNOSEPATIENTLabel = uilabel(app.DiagnosePatientTab);
            app.DIAGNOSEPATIENTLabel.Text = 'DIAGNOSE PATIENT';
            app.TabGroup2 = uitabgroup(app.DiagnosePatientTab);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.AcuteInflammationTab = uitab(app.TabGroup2);
            app.AcuteInflammationTab.Title = 'Acute Inflammation';
            app.TemperatureEditFieldLabel = uilabel(app.AcuteInflammationTab);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.AcuteInflammationTab, 'text');
            app.OccurrenceofnauseaEditFieldLabel = uilabel(app.AcuteInflammationTab);
            app.OccurrenceofnauseaEditFieldLabel.Text = 'Occurrence of nausea';
            app.OccurrenceofnauseaEditField = uieditfield(app.AcuteInflammationTab, 'text');
            app.LumbarpainEditFieldLabel = uilabel(app.AcuteInflammationTab);
            app.LumbarpainEditFieldLabel.Text = 'Lumbar pain';
            app.LumbarpainEditField = uieditfield(app.AcuteInflammationTab, 'text');
            app.UrinepushingEditFieldLabel = uilabel(app.AcuteInflammationTab);
            app.UrinepushingEditFieldLabel.Text = 'Urine pushing';
            app.UrinepushingEditField = uieditfield(app.AcuteInflammationTab, 'text');
            app.MicturitionEditFieldLabel = uilabel(app.AcuteInflammationTab);
            app.MicturitionEditFieldLabel.Text = 'Micturition';
            app.MicturitionEditField = uieditfield(app.AcuteInflammationTab, 'text');
            app.BurningofurethraEditFieldLabel = uilabel(app.AcuteInflammationTab);
            app.BurningofurethraEditFieldLabel.Text = 'Burning of urethra';
            app.BurningofurethraEditField = uieditfield(app.AcuteInflammationTab, 'text');
            app.indegreesLabel = uilabel(app.AcuteInflammationTab);
            app.indegreesLabel.Text = 'in degrees';
            app.yesnoLabel = uilabel(app.AcuteInflammationTab);
            app.yesnoLabel.Text = 'yes/no';
            app.yesnoLabel_2 = uilabel(app.AcuteInflammationTab);
            app.yesnoLabel_2.Text = 'yes/no';
            app.yesnoLabel_5 = uilabel(app.AcuteInflammationTab);
            app.yesnoLabel_5.Text = 'yes/no';
            app.yesnoLabel_3 = uilabel(app.AcuteInflammationTab);
            app.yesnoLabel_3.Text = 'yes/no';
            app.yesnoLabel_4 = uilabel(app.AcuteInflammationTab);
            app.yesnoLabel_4.Text = 'yes/no';
            app.DiagnoseAcuteButton = uibutton(app.AcuteInflammationTab, 'push');
            app.DiagnoseAcuteButton.ButtonPushedFcn = createCallbackFcn(app, @DiagnoseAcuteButtonPushed, true);
            app.DiagnoseAcuteButton.Text = 'Diagnose Acute';
            app.PredictionEditField_2 = uieditfield(app.AcuteInflammationTab, 'text');
            app.InsertsymptomsaccordingtolabelsontheleftLabel = uilabel(app.AcuteInflammationTab);
            app.InsertsymptomsaccordingtolabelsontheleftLabel.Text = 'Insert symptoms according to labels on the left';
            app.ThissoftwareisonlyauthorizedforusebymedicalexpertsLabel = uilabel(app.AcuteInflammationTab);
            app.ThissoftwareisonlyauthorizedforusebymedicalexpertsLabel.Text = 'This software is only authorized for use by medical experts';
            app.BreastCancerCoimbraTab = uitab(app.TabGroup2);
            app.BreastCancerCoimbraTab.Title = 'Breast Cancer Coimbra';
            app.AgeEditFieldLabel = uilabel(app.BreastCancerCoimbraTab);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.BreastCancerCoimbraTab, 'numeric');
            app.BMIEditFieldLabel = uilabel(app.BreastCancerCoimbraTab);
            app.BMIEditFieldLabel.Text = 'BMI';
            app.BMIEditField = uieditfield(app.BreastCancerCoimbraTab, 'numeric');
            app.GlucoseEditFieldLabel = uilabel(app.BreastCancerCoimbraTab);
            app.GlucoseEditFieldLabel.Text = 'Glucose';
            app.GlucoseEditField = uieditfield(app.BreastCancerCoimbraTab, 'numeric');
            app.InsulinEditFieldLabel = uilabel(app.BreastCancerCoimbraTab);
            app.InsulinEditFieldLabel.Text = 'Insulin';
            app.InsulinEditField = uieditfield(app.BreastCancerCoimbraTab, 'numeric');
            app.ResistinEditFieldLabel = uilabel(app.BreastCancerCoimbraTab);
            app.ResistinEditFieldLabel.Text = 'Resistin';
            app.ResistinEditField = uieditfield(app.BreastCancerCoimbraTab, 'numeric');
            app.HOMAEditFieldLabel = uilabel(app.BreastCancerCoimbraTab);
            app.HOMAEditFieldLabel.Text = 'HOMA';
            app.HOMAEditField = uieditfield(app.BreastCancerCoimbraTab, 'numeric');
            app.LeptinEditFieldLabel = uilabel(app.BreastCancerCoimbraTab);
            app.LeptinEditFieldLabel.Text = 'Leptin';
            app.LeptinEditField = uieditfield(app.BreastCancerCoimbraTab, 'numeric');
            app.AdiponectinEditFieldLabel = uilabel(app.BreastCancerCoimbraTab);
            app.AdiponectinEditFieldLabel.Text = 'Adiponectin';
            app.AdiponectinEditField = uieditfield(app.BreastCancerCoimbraTab, 'numeric');
            app.MCP1EditFieldLabel = uilabel(app.BreastCancerCoimbraTab);
            app.MCP1EditFieldLabel.Text = 'MCP.1';
            app.MCP1EditField = uieditfield(app.BreastCancerCoimbraTab, 'numeric');
            app.DiagnoseCoimbraButton = uibutton(app.BreastCancerCoimbraTab, 'push');
            app.DiagnoseCoimbraButton.ButtonPushedFcn = createCallbackFcn(app, @DiagnoseCoimbraButtonPushed, true);
            app.DiagnoseCoimbraButton.Text = 'Diagnose Coimbra';
            app.PredictionEditField = uieditfield(app.BreastCancerCoimbraTab, 'text');
            app.ThissoftwareisonlyauthorizedforusebymedicalexpertsLabel_5 = uilabel(app.BreastCancerCoimbraTab);
            app.ThissoftwareisonlyauthorizedforusebymedicalexpertsLabel_5.Text = 'This software is only authorized for use by medical experts';
            app.EnternumericalequivalentsofsymptomsLabel_4 = uilabel(app.BreastCancerCoimbraTab);
            app.EnternumericalequivalentsofsymptomsLabel_4.Text = 'Enter numerical equivalents of symptoms. Description has been provided in available diiseases section.';
            app.BreastCancerWisconsinTab = uitab(app.TabGroup2);
            app.BreastCancerWisconsinTab.Title = 'Breast Cancer Wisconsin';
            app.DiagnoseBreastCancerButton = uibutton(app.BreastCancerWisconsinTab, 'push');
            app.DiagnoseBreastCancerButton.ButtonPushedFcn = createCallbackFcn(app, @DiagnoseBreastCancerButtonPushed, true);
            app.DiagnoseBreastCancerButton.Text = 'Diagnose Breast Cancer';
            app.PredictionEditField_3 = uieditfield(app.BreastCancerWisconsinTab, 'text');
            app.ThissoftwareisonlyauthorizedforusebymedicalexpertsLabel_2 = uilabel(app.BreastCancerWisconsinTab);
            app.ThissoftwareisonlyauthorizedforusebymedicalexpertsLabel_2.Text = 'This software is only authorized for use by medical experts';
            app.ClumpThicknessEditFieldLabel = uilabel(app.BreastCancerWisconsinTab);
            app.ClumpThicknessEditFieldLabel.Text = 'Clump Thickness';
            app.ClumpThicknessEditField = uieditfield(app.BreastCancerWisconsinTab, 'numeric');
            app.UniformityofCellSizeEditField_2Label = uilabel(app.BreastCancerWisconsinTab);
            app.UniformityofCellSizeEditField_2Label.Text = 'Uniformity of Cell Size';
            app.UniformityofCellSizeEditField_2 = uieditfield(app.BreastCancerWisconsinTab, 'numeric');
            app.UniformityofCellShapeEditField_2Label = uilabel(app.BreastCancerWisconsinTab);
            app.UniformityofCellShapeEditField_2Label.Text = 'Uniformity of Cell Shape';
            app.UniformityofCellShapeEditField_2 = uieditfield(app.BreastCancerWisconsinTab, 'numeric');
            app.MarginalAdhesionEditField_2Label = uilabel(app.BreastCancerWisconsinTab);
            app.MarginalAdhesionEditField_2Label.Text = 'MarginalAdhesion';
            app.MarginalAdhesionEditField_2 = uieditfield(app.BreastCancerWisconsinTab, 'numeric');
            app.SingleEpithelialCellEditField_2Label = uilabel(app.BreastCancerWisconsinTab);
            app.SingleEpithelialCellEditField_2Label.Text = 'Single Epithelial Cell';
            app.SingleEpithelialCellEditField_2 = uieditfield(app.BreastCancerWisconsinTab, 'numeric');
            app.BareNucleiBlandEditFieldLabel = uilabel(app.BreastCancerWisconsinTab);
            app.BareNucleiBlandEditFieldLabel.Text = 'Bare Nuclei Bland';
            app.BareNucleiBlandEditField = uieditfield(app.BreastCancerWisconsinTab, 'numeric');
            app.Var8EditField_2Label = uilabel(app.BreastCancerWisconsinTab);
            app.Var8EditField_2Label.Text = 'Var8';
            app.Var8EditField_2 = uieditfield(app.BreastCancerWisconsinTab, 'numeric');
            app.NormalNucleiEditField_2Label = uilabel(app.BreastCancerWisconsinTab);
            app.NormalNucleiEditField_2Label.Text = 'Normal Nuclei';
            app.NormalNucleiEditField_2 = uieditfield(app.BreastCancerWisconsinTab, 'numeric');
            app.MitosisEditFieldLabel = uilabel(app.BreastCancerWisconsinTab);
            app.MitosisEditFieldLabel.Text = 'Mitosis';
            app.MitosisEditField = uieditfield(app.BreastCancerWisconsinTab, 'numeric');
            app.EnternumericalequivalentsofsymptomsLabel_3 = uilabel(app.BreastCancerWisconsinTab);
            app.EnternumericalequivalentsofsymptomsLabel_3.Text = 'Enter numerical equivalents of symptoms. Description has been provided in available diiseases section.';
            app.CervicalCancerTab = uitab(app.TabGroup2);
            app.CervicalCancerTab.Title = 'Cervical Cancer';
            app.DiagnoseCervicalCancerButton = uibutton(app.CervicalCancerTab, 'push');
            app.DiagnoseCervicalCancerButton.ButtonPushedFcn = createCallbackFcn(app, @DiagnoseCervicalCancerButtonPushed, true);
            app.DiagnoseCervicalCancerButton.Text = 'Diagnose Cervical Cancer';
            app.PredictionEditField_6 = uieditfield(app.CervicalCancerTab, 'text');
            app.ThissoftwareisonlyauthorizedforusebymedicalexpertsLabel_6 = uilabel(app.CervicalCancerTab);
            app.ThissoftwareisonlyauthorizedforusebymedicalexpertsLabel_6.Text = 'This software is only authorized for use by medical experts';
            app.EnternumericalequivalentsofsymptomsLabel_5 = uilabel(app.CervicalCancerTab);
            app.EnternumericalequivalentsofsymptomsLabel_5.Text = 'Enter numerical equivalents of symptoms. Description has been provided in available diiseases section.';
            app.behavior_eatingEditField_2Label = uilabel(app.CervicalCancerTab);
            app.behavior_eatingEditField_2Label.Text = 'behavior_eating';
            app.behavior_eatingEditField_2 = uieditfield(app.CervicalCancerTab, 'numeric');
            app.behavior_personalHygineEditField_2Label = uilabel(app.CervicalCancerTab);
            app.behavior_personalHygineEditField_2Label.Text = 'behavior_personalHygine';
            app.behavior_personalHygineEditField_2 = uieditfield(app.CervicalCancerTab, 'numeric');
            app.intention_aggregationEditField_2Label = uilabel(app.CervicalCancerTab);
            app.intention_aggregationEditField_2Label.Text = 'intention_aggregation';
            app.intention_aggregationEditField_2 = uieditfield(app.CervicalCancerTab, 'numeric');
            app.intention_commitmentEditField_2Label = uilabel(app.CervicalCancerTab);
            app.intention_commitmentEditField_2Label.Text = 'intention_commitment';
            app.intention_commitmentEditField_2 = uieditfield(app.CervicalCancerTab, 'numeric');
            app.attitude_consistencyEditField_2Label = uilabel(app.CervicalCancerTab);
            app.attitude_consistencyEditField_2Label.Text = 'attitude_consistency';
            app.attitude_consistencyEditField_2 = uieditfield(app.CervicalCancerTab, 'numeric');
            app.attitude_spontaneityEditField_2Label = uilabel(app.CervicalCancerTab);
            app.attitude_spontaneityEditField_2Label.Text = {'attitude_spontaneity'; ''};
            app.attitude_spontaneityEditField_2 = uieditfield(app.CervicalCancerTab, 'numeric');
            app.norm_significantPersonEditFieldLabel = uilabel(app.CervicalCancerTab);
            app.norm_significantPersonEditFieldLabel.Text = 'norm_significantPerson';
            app.norm_significantPersonEditField = uieditfield(app.CervicalCancerTab, 'numeric');
            app.perception_vulnerabilityEditField_2Label = uilabel(app.CervicalCancerTab);
            app.perception_vulnerabilityEditField_2Label.Text = 'perception_vulnerability';
            app.perception_vulnerabilityEditField_2 = uieditfield(app.CervicalCancerTab, 'numeric');
            app.perception_severityEditField_2Label = uilabel(app.CervicalCancerTab);
            app.perception_severityEditField_2Label.Text = 'perception_severity';
            app.perception_severityEditField_2 = uieditfield(app.CervicalCancerTab, 'numeric');
            app.motivation_strengthEditField_2Label = uilabel(app.CervicalCancerTab);
            app.motivation_strengthEditField_2Label.Text = 'motivation_strength';
            app.motivation_strengthEditField_2 = uieditfield(app.CervicalCancerTab, 'numeric');
            app.motivation_willingnessEditField_2Label = uilabel(app.CervicalCancerTab);
            app.motivation_willingnessEditField_2Label.Text = 'motivation_willingness';
            app.motivation_willingnessEditField_2 = uieditfield(app.CervicalCancerTab, 'numeric');
            app.socialSupport_emotionalityEditField_2Label = uilabel(app.CervicalCancerTab);
            app.socialSupport_emotionalityEditField_2Label.Text = 'socialSupport_emotionality';
            app.socialSupport_emotionalityEditField_2 = uieditfield(app.CervicalCancerTab, 'numeric');
            app.socialSupport_appreciationEditField_2Label = uilabel(app.CervicalCancerTab);
            app.socialSupport_appreciationEditField_2Label.Text = 'socialSupport_appreciation';
            app.socialSupport_appreciationEditField_2 = uieditfield(app.CervicalCancerTab, 'numeric');
            app.socialSupport_instrumentalEditFieldLabel = uilabel(app.CervicalCancerTab);
            app.socialSupport_instrumentalEditFieldLabel.Text = 'socialSupport_instrumental';
            app.socialSupport_instrumentalEditField = uieditfield(app.CervicalCancerTab, 'numeric');
            app.empowerment_knowledgeEditFieldLabel = uilabel(app.CervicalCancerTab);
            app.empowerment_knowledgeEditFieldLabel.Text = 'empowerment_knowledge';
            app.empowerment_knowledgeEditField = uieditfield(app.CervicalCancerTab, 'numeric');
            app.empowerment_abilitiesEditFieldLabel = uilabel(app.CervicalCancerTab);
            app.empowerment_abilitiesEditFieldLabel.Text = 'empowerment_abilities';
            app.empowerment_abilitiesEditField = uieditfield(app.CervicalCancerTab, 'numeric');
            app.empowerment_desiresEditFieldLabel = uilabel(app.CervicalCancerTab);
            app.empowerment_desiresEditFieldLabel.Text = 'empowerment_desires';
            app.empowerment_desiresEditField = uieditfield(app.CervicalCancerTab, 'numeric');
            app.BehaviorsexualriskEditFieldLabel = uilabel(app.CervicalCancerTab);
            app.BehaviorsexualriskEditFieldLabel.Text = 'Behavior sexual risk';
            app.BehaviorsexualriskEditField = uieditfield(app.CervicalCancerTab, 'numeric');
            app.norm_fulfillmentEditField_3Label = uilabel(app.CervicalCancerTab);
            app.norm_fulfillmentEditField_3Label.Text = 'norm_fulfillment';
            app.norm_fulfillmentEditField_3 = uieditfield(app.CervicalCancerTab, 'numeric');
            app.LymphographyTab = uitab(app.TabGroup2);
            app.LymphographyTab.Title = 'Lymphography';
            app.DiagnoseLymphographyButton = uibutton(app.LymphographyTab, 'push');
            app.DiagnoseLymphographyButton.ButtonPushedFcn = createCallbackFcn(app, @DiagnoseLymphographyButtonPushed, true);
            app.DiagnoseLymphographyButton.Text = 'Diagnose Lymphography';
            app.PredictionEditField_7 = uieditfield(app.LymphographyTab, 'text');
            app.ThissoftwareisonlyauthorizedforusebymedicalexpertsLabel_7 = uilabel(app.LymphographyTab);
            app.ThissoftwareisonlyauthorizedforusebymedicalexpertsLabel_7.Text = 'This software is only authorized for use by medical experts';
            app.EnternumericalequivalentsofsymptomsLabel_6 = uilabel(app.LymphographyTab);
            app.EnternumericalequivalentsofsymptomsLabel_6.Text = 'Enter numerical equivalents of symptoms. Description has been provided in available diiseases section.';
            app.LymphaticsblockofaffereEditFieldLabel = uilabel(app.LymphographyTab);
            app.LymphaticsblockofaffereEditFieldLabel.Text = 'Lymphatics block of affere';
            app.LymphaticsblockofaffereEditField = uieditfield(app.LymphographyTab, 'numeric');
            app.LymphaticsblockEditFieldLabel = uilabel(app.LymphographyTab);
            app.LymphaticsblockEditFieldLabel.Text = 'Lymphatics block';
            app.LymphaticsblockEditField = uieditfield(app.LymphographyTab, 'numeric');
            app.BloflymphCEditFieldLabel = uilabel(app.LymphographyTab);
            app.BloflymphCEditFieldLabel.Text = 'Bl. of lymph. C';
            app.BloflymphCEditField = uieditfield(app.LymphographyTab, 'numeric');
            app.BloflymphSEditFieldLabel = uilabel(app.LymphographyTab);
            app.BloflymphSEditFieldLabel.Text = 'Bl. of lymph. S';
            app.BloflymphSEditField = uieditfield(app.LymphographyTab, 'numeric');
            app.BypassEditFieldLabel = uilabel(app.LymphographyTab);
            app.BypassEditFieldLabel.Text = 'By pass';
            app.BypassEditField = uieditfield(app.LymphographyTab, 'numeric');
            app.ExtravasatesEditFieldLabel = uilabel(app.LymphographyTab);
            app.ExtravasatesEditFieldLabel.Text = 'Extravasates: ';
            app.ExtravasatesEditField = uieditfield(app.LymphographyTab, 'numeric');
            app.RegenerationEditFieldLabel = uilabel(app.LymphographyTab);
            app.RegenerationEditFieldLabel.Text = 'Regeneration';
            app.RegenerationEditField = uieditfield(app.LymphographyTab, 'numeric');
            app.EarlyuptakeinEditFieldLabel = uilabel(app.LymphographyTab);
            app.EarlyuptakeinEditFieldLabel.Text = 'Early uptake in';
            app.EarlyuptakeinEditField = uieditfield(app.LymphographyTab, 'numeric');
            app.LymnodesdiminEditFieldLabel = uilabel(app.LymphographyTab);
            app.LymnodesdiminEditFieldLabel.Text = 'Lym.nodes dimin';
            app.LymnodesdiminEditField = uieditfield(app.LymphographyTab, 'numeric');
            app.LymnodesenlarEditFieldLabel = uilabel(app.LymphographyTab);
            app.LymnodesenlarEditFieldLabel.Text = 'Lym.nodes enlar';
            app.LymnodesenlarEditField = uieditfield(app.LymphographyTab, 'numeric');
            app.ChangesinlymEditFieldLabel = uilabel(app.LymphographyTab);
            app.ChangesinlymEditFieldLabel.Text = 'Changes in lym';
            app.ChangesinlymEditField = uieditfield(app.LymphographyTab, 'numeric');
            app.DefectinnodeEditFieldLabel = uilabel(app.LymphographyTab);
            app.DefectinnodeEditFieldLabel.Text = 'Defect in node';
            app.DefectinnodeEditField = uieditfield(app.LymphographyTab, 'numeric');
            app.ChangesinnodeEditFieldLabel = uilabel(app.LymphographyTab);
            app.ChangesinnodeEditFieldLabel.Text = 'Changes in node';
            app.ChangesinnodeEditField = uieditfield(app.LymphographyTab, 'numeric');
            app.SpecialformsEditFieldLabel = uilabel(app.LymphographyTab);
            app.SpecialformsEditFieldLabel.Text = 'Special forms';
            app.SpecialformsEditField = uieditfield(app.LymphographyTab, 'numeric');
            app.DislocationofEditFieldLabel = uilabel(app.LymphographyTab);
            app.DislocationofEditFieldLabel.Text = 'Dislocation of';
            app.DislocationofEditField = uieditfield(app.LymphographyTab, 'numeric');
            app.ExclusionofnoEditFieldLabel = uilabel(app.LymphographyTab);
            app.ExclusionofnoEditFieldLabel.Text = 'Exclusion of no';
            app.ExclusionofnoEditField = uieditfield(app.LymphographyTab, 'numeric');
            app.NoofnodesinEditFieldLabel = uilabel(app.LymphographyTab);
            app.NoofnodesinEditFieldLabel.Text = 'No. of nodes in';
            app.NoofnodesinEditField = uieditfield(app.LymphographyTab, 'numeric');
            app.ChangesinstruEditField_2Label = uilabel(app.LymphographyTab);
            app.ChangesinstruEditField_2Label.Text = 'Changes in stru';
            app.ChangesinstruEditField_2 = uieditfield(app.LymphographyTab, 'numeric');
            app.CrossValidationEditField_2Label = uilabel(app.DiagnosePatientTab);
            app.CrossValidationEditField_2Label.Text = 'Cross Validation';
            app.CrossValidationEditField_2 = uieditfield(app.DiagnosePatientTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DiseaseUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end